classdef MeshApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResetButton                  matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        DivisionsintheYdirectionEditField  matlab.ui.control.NumericEditField
        DivisionsintheYdirectionEditFieldLabel  matlab.ui.control.Label
        ZonesontheYaxisLabel         matlab.ui.control.Label
        ZonesontheXaxisLabel         matlab.ui.control.Label
        DivisionsintherestEditField  matlab.ui.control.NumericEditField
        DivisionsintherestEditFieldLabel  matlab.ui.control.Label
        DivisionsintherefinedzoneEditField  matlab.ui.control.NumericEditField
        DivisionsintherefinedzoneEditFieldLabel  matlab.ui.control.Label
        RefinedLengthfromtopleftofgeometryEditField  matlab.ui.control.NumericEditField
        RefinedLengthfromtopleftofgeometryEditFieldLabel  matlab.ui.control.Label
        MeshGeneratorHelperLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        MainApp


        ny%Divisions in the Y direction

        L_ref% refined length (from left)
        nx_ref% divisions in refined zone
        nx_coarse% divisions in rest

        resetmesh
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResetButton.Enable = 'off';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ny = app.DivisionsintheYdirectionEditField.Value;
            app.L_ref = app.RefinedLengthfromtopleftofgeometryEditField.Value;
            app.nx_ref = app.DivisionsintherefinedzoneEditField.Value;
            app.nx_coarse = app.DivisionsintherestEditField.Value;
            app.resetmesh = false;
            app.ResetButton.Enable = 'on';
            
            app.MainApp.receiveMeshInfo(app.L_ref,app.nx_ref,app.nx_coarse,app.ny, app.resetmesh);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetmesh = true;
            app.MainApp.receiveMeshInfo(app.L_ref,app.nx_ref,app.nx_coarse,app.ny, app.resetmesh);
            app.ResetButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MeshGeneratorHelperLabel = uilabel(app.UIFigure);
            app.MeshGeneratorHelperLabel.Text = 'Mesh Generator Helper';
            app.RefinedLengthfromtopleftofgeometryEditFieldLabel = uilabel(app.UIFigure);
            app.RefinedLengthfromtopleftofgeometryEditFieldLabel.Text = 'Refined Length (from top left of geometry)';
            app.RefinedLengthfromtopleftofgeometryEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsintherefinedzoneEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsintherefinedzoneEditFieldLabel.Text = 'Divisions in the refined zone';
            app.DivisionsintherefinedzoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsintherestEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsintherestEditFieldLabel.Text = 'Divisions in the rest';
            app.DivisionsintherestEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZonesontheXaxisLabel = uilabel(app.UIFigure);
            app.ZonesontheXaxisLabel.Text = 'Zones on the X axis';
            app.ZonesontheYaxisLabel = uilabel(app.UIFigure);
            app.ZonesontheYaxisLabel.Text = 'Zones on the Y axis';
            app.DivisionsintheYdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsintheYdirectionEditFieldLabel.Text = 'Divisions in the Y direction';
            app.DivisionsintheYdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeshApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end