classdef ToolParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulateMaterialStrengthOptionalCheckBox  matlab.ui.control.CheckBox
        MaterialStrengthParametersPanel  matlab.ui.container.Panel
        FactorofSafetyEditField         matlab.ui.control.NumericEditField
        FactorofSafetyEditFieldLabel    matlab.ui.control.Label
        YeildStrengthMPaEditField       matlab.ui.control.NumericEditField
        YeildStrengthMPaEditFieldLabel  matlab.ui.control.Label
        CompressiveStrengthMPaEditField  matlab.ui.control.NumericEditField
        CompressiveStrengthMPaEditFieldLabel  matlab.ui.control.Label
        TensileStrengthMPaEditField     matlab.ui.control.NumericEditField
        TensileStrengthMPaEditFieldLabel  matlab.ui.control.Label
        DuctilityDropDown               matlab.ui.control.DropDown
        DuctilityDropDownLabel          matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ThicknessmmEditField            matlab.ui.control.NumericEditField
        ThicknessmmEditFieldLabel       matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        ToolGeometryLabel               matlab.ui.control.Label
        HeightmmEditField               matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel          matlab.ui.control.Label
        TopWidthmmEditField             matlab.ui.control.NumericEditField
        TopWidthmmEditFieldLabel        matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialLabel                   matlab.ui.control.Label
        YoungsModulusGPaEditField       matlab.ui.control.NumericEditField
        YoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        BottomWidthmmEditField          matlab.ui.control.NumericEditField
        BottomWidthmmEditFieldLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        E %Youngs modulus
        v %Poissons Ratio
        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium
        t %Thickness of material

        yeild_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        
        Sim_strength %User check for material failure
        FS %Factor of safety

        MainApp % Description
    end

    methods (Access = public)
        
        function [Xb, Yb] = isosceles_trapezium(app,Wt,Wb,H)
            % Generates coordinates of an isosceles trapezium
            % Wb = bottom width
            % Wt = top width
            % H  = height
            %
            % Output:
            % X, Y = vectors of vertex coordinates (closed shape)

            % Check validity
            if Wt < Wb
                error('Top width must be greater than the bottom width');
            end
        
            % Compute horizontal offset
            %dx = (Wb - Wt) / 2;
        
            % % Define vertices (centered at origin)
            % X = [-Wb/2; Wb/2; Wt/2; -Wt/2; -Wb/2];
            % Y = [0;0;H;H;0];
            % % Shift so top-left corner is (0,0)
            % Xb = X + Wt/2;
            % Yb = Y - H;
                % Horizontal offset (centers the top edge)
    dx = (Wb - Wt) / 2;

    % Define vertices (bottom on y = 0, centered at x = 0)
    X = [-Wb/2; Wb/2; Wb/2 - dx; -Wb/2 + dx; -Wb/2];
    Y = [0; 0; H; H; 0];
    Xb = X + Wt/2;
    Yb = Y - H;
        end
        
        function setMaterialProp(app, material)
            
            switch material
                case 'Tungsten Carbide'
               app.E = 640e9;
               app.v = 0.2;
               app.tensile_strength = 450e6;
               app.compressive_strength = 5000e6;
               app.Ductile = false;

               case 'M2 High Speed Steel'
               app.E = 200e9;
               app.v = 0.29;
               app.yeild_strength = 1550e6;
               app.Ductile = true;

               case 'Custom'
                   if app.Sim_strength == 1
                    app.E = app.YoungsModulusGPaEditField.Value*10^9;
                    app.v = app.PoissonsRatioEditField.Value;
                    app.tensile_strength = app.TensileStrengthMPaEditField.Value*10^6;
                    app.compressive_strength = app.CompressiveStrengthMPaEditField.Value*10^6;
                    app.yeild_strength = app.YeildStrengthMPaEditField.Value*10^6;
                    app.FS = app.FactorofSafetyEditField.Value;
                   else
                    app.E = app.YoungsModulusGPaEditField.Value*10^9;
                    app.v = app.PoissonsRatioEditField.Value;
                   end
                
            end        
            
        end


        function updateMaterialUI(app, material)

            switch material

               case 'Tungsten Carbide'
               app.YoungsModulusGPaEditField.Enable = 'off';
               app.PoissonsRatioEditField.Enable = 'off';
               app.DuctilityDropDown.Enable = 'off';
               
               app.TensileStrengthMPaEditField.Enable = 'off';
               app.CompressiveStrengthMPaEditField.Enable = 'off';
               app.YeildStrengthMPaEditField.Enable = 'off';

                case 'M2 High Speed Steel'
               app.YoungsModulusGPaEditField.Enable = 'off';
               app.PoissonsRatioEditField.Enable = 'off';
               app.DuctilityDropDown.Enable = 'off';

               app.TensileStrengthMPaEditField.Enable = 'off';
               app.CompressiveStrengthMPaEditField.Enable = 'off';
               app.YeildStrengthMPaEditField.Enable = 'off';

                case 'Custom'
               app.YoungsModulusGPaEditField.Enable = 'on';
               app.PoissonsRatioEditField.Enable = 'on';
               app.DuctilityDropDown.Enable = 'on';
               %app.Ductile = true;
               app.DuctilityDropDown.Value = 'Undefined';
               app.SimulateMaterialStrengthOptionalCheckBox.Enable = 'on';
               end


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % To grey out and disable the edit field
            app.YoungsModulusGPaEditField.Enable = 'off';
            app.PoissonsRatioEditField.Enable = 'off';
            app.DuctilityDropDown.Enable = 'off';
            app.TensileStrengthMPaEditField.Enable = 'off';
            app.CompressiveStrengthMPaEditField.Enable = 'off';
            app.YeildStrengthMPaEditField.Enable = 'off';
            app.MaterialDropDown.Value = 'Tungsten Carbide';

            app.MaterialStrengthParametersPanel.Enable = 'off';
            app.SimulateMaterialStrengthOptionalCheckBox.Enable = 'off';

               app.E = 640e9;
               app.v = 0.2;
               app.tensile_strength = 450e6;
               app.compressive_strength = 5000e6;
               app.Ductile = false;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
           app.Wt = app.TopWidthmmEditField.Value; %Saves value from feild
           app.Wb = app.BottomWidthmmEditField.Value;
           app.H = app.HeightmmEditField.Value;
           [app.Xb, app.Yb] = app.isosceles_trapezium(app.Wt, app.Wb, app.H);

           plot(app.UIAxes,app.Xb, app.Yb);
           axis(app.UIAxes, 'equal')

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Saving material properties
           app.t = app.ThicknessmmEditField.Value;
           app.yeild_strength = app.YeildStrengthMPaEditField.Value;
           app.tensile_strength = app.TensileStrengthMPaEditField.Value;
           app.compressive_strength = app.CompressiveStrengthMPaEditField.Value;

        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
           material = app.MaterialDropDown.Value;
           app.setMaterialProp(material);
           app.updateMaterialUI(material);
        
        end

        % Value changed function: DuctilityDropDown
        function DuctilityDropDownValueChanged(app, event)
            value = app.DuctilityDropDown.Value;

            switch value
                case 'Undefined'
                    app.TensileStrengthMPaEditField.Enable = 'off';
                    app.CompressiveStrengthMPaEditField.Enable = 'off';
                    app.YeildStrengthMPaEditField.Enable = 'off';
                case 'Ductile'

                    app.TensileStrengthMPaEditField.Enable = 'off';
                    app.CompressiveStrengthMPaEditField.Enable = 'off';
                    app.YeildStrengthMPaEditField.Enable = 'on';
                    app.Ductile = true;
                    
                case 'Brittle'
                    app.Ductile = false;
                    app.TensileStrengthMPaEditField.Enable = 'on';
                    app.CompressiveStrengthMPaEditField.Enable = 'on';
                    app.YeildStrengthMPaEditField.Enable = 'off';

            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %{
            % Read values from tool UI
    app.MainApp.Wt = app.TopWidthmmEditField.Value;
    app.MainApp.Wb = app.BottomWidthmmEditField.Value;
    app.MainApp.H  = app.HeightmmEditField.Value;
    app.MainApp.t  = app.ThicknessmmEditField.Value;

    % Generate geometry in MAIN app
    [app.MainApp.Xb, app.MainApp.Yb] = ...
        app.MainApp.isosceles_trapezium( ...
            app.MainApp.Wt, app.MainApp.Wb, app.MainApp.H);

    % Update MAIN plot
    plot(app.MainApp.UIAxes, app.MainApp.Xb, app.MainApp.Yb);
    axis(app.MainApp.UIAxes, 'equal');
            %}
    app.Wt = app.TopWidthmmEditField.Value;
    app.Wb = app.BottomWidthmmEditField.Value;
    app.H  = app.HeightmmEditField.Value;
    app.t = app.ThicknessmmEditField.Value;
    [Xb, Yb] = app.isosceles_trapezium(app.Wt, app.Wb, app.H);

    app.MainApp.receiveGeometry(Xb, Yb, app.Wt, app.Wb, app.H);

    app.MainApp.receiveMatProp(app.E,app.v,app.t,app.tensile_strength,app.compressive_strength,app.yeild_strength,app.Sim_strength, app.Ductile)
        end

        % Value changed function: SimulateMaterialStrengthOptionalCheckBox
        function SimulateMaterialStrengthOptionalCheckBoxValueChanged(app, event)
            app.Sim_strength = app.SimulateMaterialStrengthOptionalCheckBox.Value;
            if app.Sim_strength == 1
                app.MaterialStrengthParametersPanel.Enable = 'on';
          
                else
                app.MaterialStrengthParametersPanel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tool Geometry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BottomWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.BottomWidthmmEditFieldLabel.Text = 'Bottom Width (mm)';
            app.BottomWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoungsModulusGPaEditFieldLabel = uilabel(app.UIFigure);
            app.YoungsModulusGPaEditFieldLabel.Text = 'Youngs Modulus (GPa)';
            app.YoungsModulusGPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialLabel = uilabel(app.UIFigure);
            app.MaterialLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Tungsten Carbide', 'M2 High Speed Steel', 'Custom'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.TopWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.TopWidthmmEditFieldLabel.Text = 'Top Width (mm)';
            app.TopWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToolGeometryLabel = uilabel(app.UIFigure);
            app.ToolGeometryLabel.Text = 'Tool Geometry';
            app.MaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.ThicknessmmEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.ThicknessmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.PoissonsRatioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsRatioEditFieldLabel.Text = 'Poissons Ratio';
            app.PoissonsRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MaterialStrengthParametersPanel = uipanel(app.UIFigure);
            app.MaterialStrengthParametersPanel.Title = 'Material Strength Parameters';
            app.DuctilityDropDownLabel = uilabel(app.MaterialStrengthParametersPanel);
            app.DuctilityDropDownLabel.Text = 'Ductility';
            app.DuctilityDropDown = uidropdown(app.MaterialStrengthParametersPanel);
            app.DuctilityDropDown.Items = {'Ductile', 'Brittle', 'Undefined'};
            app.DuctilityDropDown.ValueChangedFcn = createCallbackFcn(app, @DuctilityDropDownValueChanged, true);
            app.DuctilityDropDown.Value = 'Undefined';
            app.TensileStrengthMPaEditFieldLabel = uilabel(app.MaterialStrengthParametersPanel);
            app.TensileStrengthMPaEditFieldLabel.Text = 'Tensile Strength (MPa)';
            app.TensileStrengthMPaEditField = uieditfield(app.MaterialStrengthParametersPanel, 'numeric');
            app.CompressiveStrengthMPaEditFieldLabel = uilabel(app.MaterialStrengthParametersPanel);
            app.CompressiveStrengthMPaEditFieldLabel.Text = 'Compressive Strength (MPa)';
            app.CompressiveStrengthMPaEditField = uieditfield(app.MaterialStrengthParametersPanel, 'numeric');
            app.YeildStrengthMPaEditFieldLabel = uilabel(app.MaterialStrengthParametersPanel);
            app.YeildStrengthMPaEditFieldLabel.Text = 'Yeild Strength (MPa)';
            app.YeildStrengthMPaEditField = uieditfield(app.MaterialStrengthParametersPanel, 'numeric');
            app.FactorofSafetyEditFieldLabel = uilabel(app.MaterialStrengthParametersPanel);
            app.FactorofSafetyEditFieldLabel.Text = 'Factor of Safety';
            app.FactorofSafetyEditField = uieditfield(app.MaterialStrengthParametersPanel, 'numeric');
            app.FactorofSafetyEditField.Value = 1;
            app.SimulateMaterialStrengthOptionalCheckBox = uicheckbox(app.UIFigure);
            app.SimulateMaterialStrengthOptionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulateMaterialStrengthOptionalCheckBoxValueChanged, true);
            app.SimulateMaterialStrengthOptionalCheckBox.Text = 'Simulate Material Strength (Optional)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end