classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DisplaydeformedstructureButton  matlab.ui.control.Button
        DisplaygivenplateButton         matlab.ui.control.Button
        PostprocessButton               matlab.ui.control.Button
        SolveButton                     matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        GetdataButton                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        n_element
        n_nodes 

        E 
        A

        ncon 

        X 
        Y

        NDU   
        dzero 

        F 

        v 
        t 

        % Initiate Matrices
        KE 
        K  
    
        KM 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetdataButton
        function GetdataButtonPushed(app, event)
            data = readmatrix('structure_data.xlsx');

            app.n_element = data(1,1);
            app.n_nodes   = data(1,2);

            app.E = data(1,8);
            app.A = data(1,9);
            
            app.ncon = [data(:,3), data(:,4), data(:,5)];
            
            app.X = data(:,6);
            app.Y = data(:,7);
            
            app.NDU   = data(1,11);
            app.dzero = data(:,12);
            
            app.F = data(:,10);
            
            app.v = data(1,13);
            app.t = data(1,14);
            
            % Initiate Matrices
            app.KE = zeros(6);
            app.K  = zeros(2*app.n_nodes);
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            app.K = zeros(2*app.n_nodes, 2*app.n_nodes);

            for i = 1:app.n_element

                KE = app.pre_processing(i);

                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                n3 = app.ncon(i,3);

                ROC = zeros(1,6);
                ROC(1) = 2*n1 - 1;
                ROC(2) = 2*n1;
                ROC(3) = 2*n2 - 1;
                ROC(4) = 2*n2;
                ROC(5) = 2*n3 - 1;
                ROC(6) = 2*n3;

                for IX = 1:6
                    MI = ROC(IX);
                    for JX = 1:6
                        MJ = ROC(JX);
                        app.K(MI,MJ) = app.K(MI,MJ) + KE(IX,JX);
                    end
                end
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.KM = app.K;

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(n,:) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(:,n) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                app.KM(n,n) = 1;
            end

            app.U = app.KM \ app.F;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GetdataButton = uibutton(app.UIFigure, 'push');
            app.GetdataButton.ButtonPushedFcn = createCallbackFcn(app, @GetdataButtonPushed, true);
            app.GetdataButton.Text = 'Getdata';
            app.PreprocessButton = uibutton(app.UIFigure, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.PostprocessButton = uibutton(app.UIFigure, 'push');
            app.PostprocessButton.Text = 'Postprocess';
            app.DisplaygivenplateButton = uibutton(app.UIFigure, 'push');
            app.DisplaygivenplateButton.Text = 'Display given plate';
            app.DisplaydeformedstructureButton = uibutton(app.UIFigure, 'push');
            app.DisplaydeformedstructureButton.Text = 'Display deformed structure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end