classdef thermal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        BevelledToolGeometryCheckBox   matlab.ui.control.CheckBox
        ToolContactTempLabel           matlab.ui.control.Label
        PoissonsRatioLabel             matlab.ui.control.Label
        YoungsModulosGPaLabel          matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ToolContactTempEditField       matlab.ui.control.NumericEditField
        ToolContactTempEditFieldLabel  matlab.ui.control.Label
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        MaterialPropertiesLabel        matlab.ui.control.Label
        ToolGeometryLabel              matlab.ui.control.Label
        LoadBevelledToolButton         matlab.ui.control.Button
        ThicknessmEditField            matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel       matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        BottomWidthmEditField          matlab.ui.control.NumericEditField
        BottomWidthmEditFieldLabel     matlab.ui.control.Label
        TopWidtmEditField              matlab.ui.control.NumericEditField
        TopWidtmEditFieldLabel         matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

        n_elements % Number of elements generated in the mesh
        n_nodes %Number of nodes in the generated mesh

        E %Youngs modulus of the selected material
        A %Area of the element
        
        ncon %Connection between nodes

        X %Coordiantes of nodes 
        Y

        NDU %Nodal degree of freedom   
        dzero %Setting some nodes to zero degrees of freedom (no displacment)

        F %Force vector acting on the nodes 
        v %Poissons ratio
        t %Thickeness of material

        % Initiate Matrices
        KE %Elemntal stiffness matrix
        K  %Global sitffness matrix of the piece
    
        KM %Duplicating 

        
        Wt %Top width of the isoceles trapezium
        Wb %Bottom width of the isoceles trapezium
        H %Height of isoceles trapezium

        U %Displacemnt matrix

        Ex %Strain in the X
        Ey %Strain in the Y
        Gxy %Shearing strain 

        Sx %Stress in the X
        Sy % Stress in the Y
        Sxy % Shearing stress

        Ux %Displacment in the X
        Uy %Displacement in the Y
        Umag %magnitude of the total displacement

        yield_strength %yeild strength of the material (optional)
        tensile_strength %Ultimate tensile strength
        compressive_strength %Compressive strength
        Ductile %How ductile is material
        Xb %X coordinates of the tool geometry boundary
        Yb %Y coordiantes of the tool geometry boundary
        Sim_strength
        ToolApp
        MeshAppp
        

        L_ref% refined length (from left)
        nx_ref% divisions in refined zone
        nx_coarse% divisions in rest
        ny%Divisions in the Y direction

        resetmesh %Keep track of the reset mesh state
        ANSYSmesh %keep track of the laoding ansys mesh

        Fc %Cutting force
        Ft %Thrust force
        scale %Scale of deformity

        contactTemp

        check
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            app.TopWidtmEditField.Enable = 'off';
            app.BottomWidthmEditField.Enable = 'off';
            app.HeightmEditField.Enable = 'off';
            app.ThicknessmEditField.Enable = 'off';
            %}
            app.LoadBevelledToolButton.Enable = 'off';
            app.check = false;
        end

        % Callback function: not associated with a component
        function LoadCustomGeometryButtonPushed(app, event)
            app.TopWidtmEditField.Enable = 'on';
            app.BottomWidthmEditField.Enable = 'on';
            app.HeightmEditField.Enable = 'on';
            app.ThicknessmEditField.Enable = 'on';

            app.check = true;

            app.Xb = [0, 0.00078, 0.006, 0.0112, 0.012];
            app.Yb = [0, -0.004, -0.004, -0.004, 0];
            disp(app.Xb)
            disp(app.Yb)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            app.Xb = [];
            app.Yb = [];


            app.E = 0;
            app.v = 0;
            app.contactTemp = 0;
            app.t = 0;
                
            cla(app.UIAxes3)
            plot(app.UIAxes3, app.Xb, app.Yb);
            axis(app.UIAxes3, 'equal');
            
            app.check = false;

            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', app.E);
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.check == false
            app.Wt = app.TopWidtmEditField.Value;
            app.Wb = app.BottomWidthmEditField.Value;
            app.H = app.HeightmEditField.Value;
            [app.Xb, app.Yb] = CreateCustomTool(app.Wt,app.Wb,app.H);

            
            plot(app.UIAxes3, app.Xb, app.Yb);
            axis(app.UIAxes3, 'equal');
            end
            if app.check == true
            plot(app.UIAxes3, app.Xb, app.Yb);
            axis(app.UIAxes3, 'equal');
            end
            
            app.t = app.ThicknessmEditField.Value;

            app.contactTemp = app.ToolContactTempEditField.Value;

            material = app.MaterialDropDown.Value;
            switch material

                case 'Tungsten Carbide'
                    app.E = 640e9;
                    app.v = 0.2;

                case 'M2 High Speed Steel'
                    app.E = 200e9;
                    app.v = 0.29;
            end

            disp('***Youngs Modulos***')
            disp(app.E);
            disp('***Poissons Ratio***')
            disp(app.v);

            disp('Boundary coordinates for the tool geometry X, Y')
            disp(app.Xb);
            disp(app.Yb);

            app.YoungsModulosGPaLabel.Text = sprintf('Youngs Modulus (GPa): %.2f', (app.E/10^9));
            app.PoissonsRatioLabel.Text = sprintf('Poissons Ratio: %.2f', app.v);
            app.ToolContactTempLabel.Text = sprintf('Tool Contact Temperature (C): %.2f', app.contactTemp);
        end

        % Value changed function: BevelledToolGeometryCheckBox
        function BevelledToolGeometryCheckBoxValueChanged(app, event)
            value = app.BevelledToolGeometryCheckBox.Value;
            if value == 1
                %User can now laod Bevelled tool geometry
                app.LoadBevelledToolButton.Enable = 'on';

                %Grey out option to input values for custom geometry
                app.TopWidtmEditField.Enable = 'off';
                app.BottomWidthmEditField.Enable = 'off';
                app.HeightmEditField.Enable = 'off';
                app.ThicknessmEditField.Enable = 'off';
            
            else
                app.LoadBevelledToolButton.Enable = 'off';
                %Return option tool input custom tool parameters
                app.TopWidtmEditField.Enable = 'on';
                app.BottomWidthmEditField.Enable = 'on';
                app.HeightmEditField.Enable = 'on';
                app.ThicknessmEditField.Enable = 'on';

                app.StatusLamp.Color = 'red';
            end
            
        end

        % Button pushed function: LoadBevelledToolButton
        function LoadBevelledToolButtonPushed(app, event)
            app.StatusLamp.Color = 'green';

            app.check = true;
app.Xb = [0, 0.00078, 0.006, 0.0112, 0.012, 0.006, 0];
app.Yb = [0, -0.004, -0.004, -0.004, 0, 0, 0];

% =========================
% Convert to BEVELLED geometry
% =========================

L = 0.3e-3;
theta = 45;

Xb = app.Xb;
Yb = app.Yb;

% --- Nose region indices (based on your geometry) ---
idx_left  = 3;
idx_tip   = 4;
idx_right = 5;

P_tip = [Xb(idx_tip), Yb(idx_tip)];

% --- Bevel directions ---
d1 = [cosd(theta), sind(theta)];
d2 = [cosd(180-theta), sind(180-theta)];

% --- Bevel points ---
P1 = P_tip + L * d1;
P2 = P_tip + L * d2;

% --- Build new boundary ---
app.Xb = [
    Xb(1:idx_left), ...
    P1(1), ...
    P_tip(1), ...
    P2(1), ...
    Xb(idx_right:end)
];

app.Yb = [
    Yb(1:idx_left), ...
    P1(2), ...
    P_tip(2), ...
    P2(2), ...
    Yb(idx_right:end)
];

% --- Close loop ---
app.Xb(end+1) = app.Xb(1);
app.Yb(end+1) = app.Yb(1);
            disp(app.Xb)
            disp(app.Yb)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Tool Geometry')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TopWidtmEditFieldLabel = uilabel(app.Tab);
            app.TopWidtmEditFieldLabel.Text = 'Top Widt (m)';
            app.TopWidtmEditField = uieditfield(app.Tab, 'numeric');
            app.BottomWidthmEditFieldLabel = uilabel(app.Tab);
            app.BottomWidthmEditFieldLabel.Text = 'Bottom Width (m)';
            app.BottomWidthmEditField = uieditfield(app.Tab, 'numeric');
            app.HeightmEditFieldLabel = uilabel(app.Tab);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.Tab, 'numeric');
            app.ThicknessmEditFieldLabel = uilabel(app.Tab);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessmEditField = uieditfield(app.Tab, 'numeric');
            app.LoadBevelledToolButton = uibutton(app.Tab, 'push');
            app.LoadBevelledToolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBevelledToolButtonPushed, true);
            app.LoadBevelledToolButton.Text = 'Load Bevelled Tool';
            app.ToolGeometryLabel = uilabel(app.Tab);
            app.ToolGeometryLabel.Text = 'Tool Geometry';
            app.MaterialPropertiesLabel = uilabel(app.Tab);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.MaterialDropDownLabel = uilabel(app.Tab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.Tab);
            app.MaterialDropDown.Items = {'Tungsten Carbide', 'M2 High Speed Steel'};
            app.MaterialDropDown.Value = 'Tungsten Carbide';
            app.ToolContactTempEditFieldLabel = uilabel(app.Tab);
            app.ToolContactTempEditFieldLabel.Text = 'Tool Contact Temp';
            app.ToolContactTempEditField = uieditfield(app.Tab, 'numeric');
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.Tab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.YoungsModulosGPaLabel = uilabel(app.Tab);
            app.YoungsModulosGPaLabel.Text = 'Youngs Modulos (GPa):';
            app.PoissonsRatioLabel = uilabel(app.Tab);
            app.PoissonsRatioLabel.Text = 'Poissons Ratio:';
            app.ToolContactTempLabel = uilabel(app.Tab);
            app.ToolContactTempLabel.Text = 'Tool Contact Temp: ';
            app.BevelledToolGeometryCheckBox = uicheckbox(app.Tab);
            app.BevelledToolGeometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @BevelledToolGeometryCheckBoxValueChanged, true);
            app.BevelledToolGeometryCheckBox.Text = 'Bevelled Tool Geometry';
            app.StatusLampLabel = uilabel(app.Tab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.Tab2);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end